classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        card5                          matlab.ui.control.Image
        dealer_card_1                  matlab.ui.control.Image
        PlayButton                     matlab.ui.control.Button
        YourEarningsTextArea           matlab.ui.control.TextArea
        YourEarningsTextAreaLabel      matlab.ui.control.Label
        error_message_window           matlab.ui.control.TextArea
        BettingAmountEditField         matlab.ui.control.NumericEditField
        BettingAmountEditFieldLabel    matlab.ui.control.Label
        MinimumBettingAmountEditField  matlab.ui.control.NumericEditField
        MinimumBettingAmountEditFieldLabel  matlab.ui.control.Label
        dealer_card4                   matlab.ui.control.Image
        dealer_card3                   matlab.ui.control.Image
        dealer_card2                   matlab.ui.control.Image
        DealersHandLabel               matlab.ui.control.Label
        YourHandLabel                  matlab.ui.control.Label
        card4                          matlab.ui.control.Image
        SplitButton                    matlab.ui.control.Button
        card3                          matlab.ui.control.Image
        card2                          matlab.ui.control.Image
        card1                          matlab.ui.control.Image
        StayButton                     matlab.ui.control.Button
        HitButton                      matlab.ui.control.Button
        WelcometoOnlineBlackjackLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        deck_of_cards = [1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1; 1 1 1 1];
        %current_card = [];
        user_count = 0;
        dealer_count = 0;
        hit_button_pressed = 0;
        card1 = app1.card1;
        user_image_list = [app.card2, app.card3, app.card4, app.card5];
        dealer_image_list = [app.dealer_card1, app.dealer_card2, app.dealer_card3, app.dealer_card4];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MinimumBettingAmountEditField
        function MinimumBettingAmountEditFieldValueChanged2(app, event)

            minimum_bet = app.MinimumBettingAmountEditField.Value;
            placed_bet = app.BettingAmountEditField.Value;
            if placed_bet < 0
                app.error_message_window.Value = 'Please enter a valid betting amount before hitting play.  ';
            elseif placed_bet < minimum_bet
                app.error_message_window.Value = 'Please enter a value over the minimum betting amount before hitting play.';
            else
                app.error_message_window.Value = '';
            end
        end

        % Value changed function: BettingAmountEditField
        function BettingAmountEditFieldValueChanged(app, event)
            placed_bet = app.BettingAmountEditField.Value;
            minimum_bet = app.MinimumBettingAmountEditField.Value;
            if placed_bet < 0
                app.error_message_window.Value = 'Please enter a valid betting amount before hitting play.  ';
            elseif placed_bet < minimum_bet
                app.error_message_window.Value = 'Please enter a value over the minimum betting amount before hitting play.';
            else
                app.error_message_window.Value = '';
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)

            [value, suit] = size(app.deck_of_cards);
            card_in_deck = 0;
            while card_in_deck == 0
                row_index = randi(value, 1, 1);
                column_index = randi(suit, 1, 1);
                card_in_deck = app.deck_of_cards(row_index, column_index);
            end
            filename = strcat('C:\Users\prami\OneDrive\Desktop\CLPS_0950\CLPS_0950_project\', num2str(row_index), '_', num2str(column_index), '.png');
            app.card1.ImageSource = filename;
            app.deck_of_cards(row_index, column_index) = 0;
            app.user_count = app.user_count + row_index;
            card_in_deck = 0;
            while card_in_deck == 0
                row_index = randi(value, 1, 1);
                column_index = randi(suit, 1, 1);
                card_in_deck = app.deck_of_cards(row_index, column_index);
            end
            filename = strcat('C:\Users\prami\OneDrive\Desktop\CLPS_0950\CLPS_0950_project\', num2str(row_index), '_', num2str(column_index), '.png');
            app.dealer_card_1.ImageSource = filename;
            app.deck_of_cards(row_index, column_index) = 0;
            app.dealer_count = app.dealer_count + row_index;
            
        end

        % Button pushed function: HitButton
        function HitButtonPushed(app, event)
            app.hit_button_pressed = app.hit_button_pressed + 1;
            [value, suit] = size(app.deck_of_cards); 
            card_in_deck = 0;
            while card_in_deck == 0
                row_index = randi(value, 1, 1);
                column_index = randi(suit, 1, 1);
                card_in_deck = app.deck_of_cards(row_index, column_index);
            end
            filename = strcat('C:\Users\prami\OneDrive\Desktop\CLPS_0950\CLPS_0950_project\', num2str(row_index), '_', num2str(column_index), '.png');
            app.user_image_list(app.hit_button_pressed).ImageSource= filename;
            app.deck_of_cards(row_index, column_index) = 0;
            app.user_count = app.user_count + row_index;
        end

        % Button pushed function: StayButton
        function StayButtonPushed(app, event)
            while app.dealer_count <= 16
                card_in_deck = 0;
                while card_in_deck == 0
                row_index = randi(value, 1, 1);
                column_index = randi(suit, 1, 1);
                card_in_deck = app.deck_of_cards(row_index, column_index);
                end
                app.dealer_count = app.dealer_count + row_index;
            end
            if app.dealer_count > 21 && app.user_count < 21
                app.YourEarningsTextArea = 2 * placed_bet;
            elseif app.dealer_count >= 17
                if app.user_count > 21
                    app.error_message_window.Value = 'You went over 21; therefore, you lost the game :(';
                    app.YourEarningsTextArea.Value = -(placed_bet);
                elseif app.user_count == 21
                    app.error_message_window.Value = 'Congratulations, you got a Blackjack! ';
                    app.YourEarningsTextArea.Value = 1.5 * placed_bet;
                elseif app.user_count < 21
                    if app.user_count > app.dealer_count
                        app.YourEarningsTextArea = 2 * placed_bet;
                    else
                        app.YourEarningsTextArea = -(placed_bet);
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WelcometoOnlineBlackjackLabel = uilabel(app.UIFigure);
            app.WelcometoOnlineBlackjackLabel.Text = 'Welcome to Online Blackjack!';
            app.HitButton = uibutton(app.UIFigure, 'push');
            app.HitButton.ButtonPushedFcn = createCallbackFcn(app, @HitButtonPushed, true);
            app.HitButton.Text = 'Hit';
            app.StayButton = uibutton(app.UIFigure, 'push');
            app.StayButton.ButtonPushedFcn = createCallbackFcn(app, @StayButtonPushed, true);
            app.StayButton.Text = 'Stay';
            app.card1 = uiimage(app.UIFigure);
            app.card2 = uiimage(app.UIFigure);
            app.card3 = uiimage(app.UIFigure);
            app.SplitButton = uibutton(app.UIFigure, 'push');
            app.SplitButton.Text = 'Split';
            app.card4 = uiimage(app.UIFigure);
            app.YourHandLabel = uilabel(app.UIFigure);
            app.YourHandLabel.Text = 'Your Hand';
            app.DealersHandLabel = uilabel(app.UIFigure);
            app.DealersHandLabel.Text = 'Dealer''s Hand';
            app.dealer_card2 = uiimage(app.UIFigure);
            app.dealer_card3 = uiimage(app.UIFigure);
            app.dealer_card4 = uiimage(app.UIFigure);
            app.MinimumBettingAmountEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumBettingAmountEditFieldLabel.Text = 'Minimum Betting Amount';
            app.MinimumBettingAmountEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumBettingAmountEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumBettingAmountEditFieldValueChanged2, true);
            app.MinimumBettingAmountEditField.Value = 10;
            app.BettingAmountEditFieldLabel = uilabel(app.UIFigure);
            app.BettingAmountEditFieldLabel.Text = 'Betting Amount';
            app.BettingAmountEditField = uieditfield(app.UIFigure, 'numeric');
            app.BettingAmountEditField.ValueChangedFcn = createCallbackFcn(app, @BettingAmountEditFieldValueChanged, true);
            app.BettingAmountEditField.Value = 15;
            app.error_message_window = uitextarea(app.UIFigure);
            app.error_message_window.Value = {'Good Luck!'};
            app.YourEarningsTextAreaLabel = uilabel(app.UIFigure);
            app.YourEarningsTextAreaLabel.Text = 'Your Earnings';
            app.YourEarningsTextArea = uitextarea(app.UIFigure);
            app.YourEarningsTextArea.Value = {'0'; '0'};
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.dealer_card_1 = uiimage(app.UIFigure);
            app.card5 = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end